classdef beamProfileLoader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Beamprofileloaderforplan2DLabel  matlab.ui.control.Label
        NotreadyLampLabel       matlab.ui.control.Label
        NotreadyLamp            matlab.ui.control.Lamp
        ExitButton              matlab.ui.control.Button
        ConfirmButton           matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        UIAxes                  matlab.ui.control.UIAxes
        ChoosefileButton        matlab.ui.control.Button
        dxdycmEditFieldLabel    matlab.ui.control.Label
        dxdycmEditField         matlab.ui.control.NumericEditField
        CreateGaussianButton    matlab.ui.control.Button
        sigmaXcmEditFieldLabel  matlab.ui.control.Label
        sigmaXcmEditField       matlab.ui.control.NumericEditField
        sigmaYcmEditFieldLabel  matlab.ui.control.Label
        sigmaYcmEditField       matlab.ui.control.NumericEditField
        CreatepinHoleButton     matlab.ui.control.Button
        radiuscmEditFieldLabel  matlab.ui.control.Label
        radiuscmEditField       matlab.ui.control.NumericEditField
        errMsgLabel             matlab.ui.control.Label
        XSizecmEditFieldLabel   matlab.ui.control.Label
        XSizecmEditField        matlab.ui.control.NumericEditField
        YSizecmEditFieldLabel   matlab.ui.control.Label
        YSizecmEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        beamProfile     % Description
        parentApp       % Description
        isReady % Description
    end
    
    methods (Access = private)
        
        function showErrorMsg(app, errMsg)
            app.errMsgLabel.Text = errMsg;
            app.errMsgLabel.Visible = true;
            app.NotreadyLampLabel.Text = 'Not ready';
            app.NotreadyLamp.Color = [1 0 0];
            app.ConfirmButton.Enable = false;
            cla(app.UIAxes);
        end
        
        function clearErrorMsg(app)
            app.errMsgLabel.Text = '';
            app.errMsgLabel.Visible = false;
        end
        
        function verifyReady(app)
            if ~isempty(app.beamProfile) && app.beamProfile.passMinMaxTests
                app.isReady = true;
                app.NotreadyLampLabel.Text = 'Ready';
                app.NotreadyLamp.Color = [0 1 0];
                app.ConfirmButton.Enable = true;
                app.clearErrorMsg
                imagesc(app.beamProfile.getAxisValues('X'), ...
                    app.beamProfile.getAxisValues('Y'), ...
                    app.beamProfile.data, 'Parent', app.UIAxes);
                halfX = app.XSizecmEditField.Value / 2;
                halfY = app.YSizecmEditField.Value / 2;
                app.UIAxes.XTick = [-halfX halfX];
                app.UIAxes.XTickLabel = [-halfX halfX];
                app.UIAxes.YTick = [-halfY halfY];
                app.UIAxes.YTickLabel = [-halfY halfY];
            else
                app.isReady = false;
                app.showErrorMsg('Beam profile not ready');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;
            app.parentApp.logLine('Opening beam profile loader.')
            if isfield(parentApp,'N0') && ~isempty(parentApp.N0)
                app.beamProfile = parentApp.N0;
                app.XSizecmEditField.Value = app.beamProfile.maxX - app.beamProfile.minX;
                app.YSizecmEditField.Value = app.beamProfile.maxY - app.beamProfile.minY;
                app.dxdycmEditField.Value = app.beamProfile.dx;  
                app.verifyReady
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.parentApp.logLine('Closing beam profile loader.')
            delete(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest(event);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.verifyReady
            if app.isReady
                app.parentApp.setBeamProfile(app.beamProfile);
                app.UIFigureCloseRequest(event);
            end
        end

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            [filename, filepath] = uigetfile('beamProf/*.mat');
            fullPath = fullfile(filepath, filename);
            loadedProf = load(fullPath, 'beamProfile'); 
            app.beamProfile = loadedProf.beamProfile;
            app.XSizecmEditField.Value = app.beamProfile.maxX - app.beamProfile.minX;
            app.YSizecmEditField.Value = app.beamProfile.maxY - app.beamProfile.minY;
            app.dxdycmEditField.Value = app.beamProfile.dx;
            app.verifyReady;
        end

        % Button pushed function: CreatepinHoleButton
        function CreatepinHoleButtonPushed(app, event)
            XSize = app.XSizecmEditField.Value;
            YSize = app.YSizecmEditField.Value;
            dxy = app.dxdycmEditField.Value;
            radius = app.radiuscmEditField.Value;
            if isnan(XSize) || XSize <= 0 || XSize > 20
                app.showErrorMsg('X Size must be between 0-20 cm.')
                return;
            end
            
            if isnan(YSize) || YSize <= 0 || YSize > 20
                app.showErrorMsg('Y Size must be between 0-20 cm.')
                return;
            end
            
            if isnan(dxy) || dxy <= 0 || dxy > 1
                app.showErrorMsg('dxy must be between 0-1 cm.')
                return;
            end
            
            if isnan(radius) || radius <= 0 || radius >= min(XSize,YSize) / 2
                app.showErrorMsg('Radius must fit in profile.')
                return;
            end
            app.beamProfile = createPinHole(dxy, XSize, YSize, radius);
            app.verifyReady;
        end

        % Button pushed function: CreateGaussianButton
        function CreateGaussianButtonPushed(app, event)
            XSize = app.XSizecmEditField.Value;
            YSize = app.YSizecmEditField.Value;
            dxy = app.dxdycmEditField.Value;
            sigmaX = app.sigmaXcmEditField.Value;
            sigmaY = app.sigmaYcmEditField.Value;
            
            if isnan(XSize) || XSize <= 0 || XSize > 20
                app.showErrorMsg('X Size must be between 0-20 cm.')
                return;
            end
            
            if isnan(YSize) || YSize <= 0 || YSize > 20
                app.showErrorMsg('Y Size must be between 0-20 cm.')
                return;
            end
            
            if isnan(dxy) || dxy <= 0 || dxy > 1
                app.showErrorMsg('dxy must be between 0-1 cm.')
                return;
            end
            
            if isnan(sigmaX) || sigmaX <= 0
                app.showErrorMsg('sigmaX must be positive.')
                return;
            end
            
            if isnan(sigmaY) || sigmaY <= 0
                app.showErrorMsg('sigmaY must be positive.')
                return;
            end
            
            app.beamProfile = createGaussProfile(dxy, dxy, XSize, YSize, sigmaX, sigmaY);
            app.verifyReady;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Beamprofileloaderforplan2DLabel = uilabel(app.UIFigure);
            app.Beamprofileloaderforplan2DLabel.Text = 'Beam profile loader for plan2D';
            app.NotreadyLampLabel = uilabel(app.UIFigure);
            app.NotreadyLampLabel.Text = 'Not ready';
            app.NotreadyLamp = uilamp(app.UIFigure);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Image = uiimage(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 1];
            app.UIAxes.YTick = [0 1];
            app.ChoosefileButton = uibutton(app.UIFigure, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.dxdycmEditFieldLabel = uilabel(app.UIFigure);
            app.dxdycmEditFieldLabel.Text = 'dx / dy [cm]';
            app.dxdycmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CreateGaussianButton = uibutton(app.UIFigure, 'push');
            app.CreateGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGaussianButtonPushed, true);
            app.CreateGaussianButton.Text = 'Create Gaussian';
            app.sigmaXcmEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaXcmEditFieldLabel.Text = 'sigmaX [cm]';
            app.sigmaXcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaYcmEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaYcmEditFieldLabel.Text = 'sigmaY [cm]';
            app.sigmaYcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CreatepinHoleButton = uibutton(app.UIFigure, 'push');
            app.CreatepinHoleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatepinHoleButtonPushed, true);
            app.CreatepinHoleButton.Text = 'Create pinHole';
            app.radiuscmEditFieldLabel = uilabel(app.UIFigure);
            app.radiuscmEditFieldLabel.Text = 'radius [cm]';
            app.radiuscmEditField = uieditfield(app.UIFigure, 'numeric');
            app.errMsgLabel = uilabel(app.UIFigure);
            app.errMsgLabel.Text = '';
            app.XSizecmEditFieldLabel = uilabel(app.UIFigure);
            app.XSizecmEditFieldLabel.Text = 'XSize [cm]';
            app.XSizecmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YSizecmEditFieldLabel = uilabel(app.UIFigure);
            app.YSizecmEditFieldLabel.Text = 'YSize [cm]';
            app.YSizecmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beamProfileLoader(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end